classdef Kinetic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AveragePowerOutputEditField  matlab.ui.control.NumericEditField
        AveragePowerOutputEditFieldLabel  matlab.ui.control.Label
        CalculateAveragePowerOutputButton  matlab.ui.control.Button
        AverageFlowEditField         matlab.ui.control.NumericEditField
        AverageFlowEditFieldLabel    matlab.ui.control.Label
        Q70EditField                 matlab.ui.control.NumericEditField
        Q70EditFieldLabel            matlab.ui.control.Label
        Q10EditField                 matlab.ui.control.NumericEditField
        Q10EditFieldLabel            matlab.ui.control.Label
        SweptareaofturbineEditField  matlab.ui.control.NumericEditField
        SweptareaofturbineEditFieldLabel  matlab.ui.control.Label
        CrosssectionalareaEditField  matlab.ui.control.NumericEditField
        CrosssectionalareaEditFieldLabel  matlab.ui.control.Label
        EfficiencyEditField          matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel     matlab.ui.control.Label
        CalculateGraphButton         matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateGraphButton
        function CalculateGraphButtonPushed(app, event)
            Q10 = app.Q10EditField.Value;
            Q70 = app.Q70EditField.Value;
            nElements = 20;
            stepSize = (Q70-Q10)/(nElements-1);
            Flow = Q10:stepSize:Q70;
            Rho = 1000;
            Cp = app.EfficiencyEditField.Value;
            A = app.CrosssectionalareaEditField.Value;
            XS = app.CrosssectionalareaEditField.Value; % Cross section of entire river (currently ballpark)
            KineticQ = Flow/XS;
            
            PKinetic = Cp*0.5*Rho*A*KineticQ.^3/1000;
            y = PKinetic;
            plot(app.UIAxes,y);
        end

        % Button pushed function: CalculateAveragePowerOutputButton
        function CalculateAveragePowerOutputButtonPushed(app, event)
            AverageFlow = app.AverageFlowEditField.Value;
            Rho = 1000;
            Cp = app.EfficiencyEditField.Value;
            A = app.CrosssectionalareaEditField.Value;
            AveragePower = Cp*0.5*Rho*A*AverageFlow;
            
            app.AveragePowerOutputEditField.Value = AveragePower;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Flow against kinetic power generation')
            xlabel(app.UIAxes, 'Flow')
            ylabel(app.UIAxes, 'Kinetic Power Generated')
            zlabel(app.UIAxes, 'Z')
            app.CalculateGraphButton = uibutton(app.UIFigure, 'push');
            app.CalculateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateGraphButtonPushed, true);
            app.CalculateGraphButton.Text = 'Calculate Graph';
            app.EfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CrosssectionalareaEditFieldLabel = uilabel(app.UIFigure);
            app.CrosssectionalareaEditFieldLabel.Text = 'Cross sectional area';
            app.CrosssectionalareaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweptareaofturbineEditFieldLabel = uilabel(app.UIFigure);
            app.SweptareaofturbineEditFieldLabel.Text = 'Swept area of turbine';
            app.SweptareaofturbineEditField = uieditfield(app.UIFigure, 'numeric');
            app.Q10EditFieldLabel = uilabel(app.UIFigure);
            app.Q10EditFieldLabel.Text = 'Q10';
            app.Q10EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q70EditFieldLabel = uilabel(app.UIFigure);
            app.Q70EditFieldLabel.Text = 'Q70';
            app.Q70EditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageFlowEditFieldLabel = uilabel(app.UIFigure);
            app.AverageFlowEditFieldLabel.Text = 'Average Flow';
            app.AverageFlowEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateAveragePowerOutputButton = uibutton(app.UIFigure, 'push');
            app.CalculateAveragePowerOutputButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAveragePowerOutputButtonPushed, true);
            app.CalculateAveragePowerOutputButton.Text = 'Calculate Average Power Output';
            app.AveragePowerOutputEditFieldLabel = uilabel(app.UIFigure);
            app.AveragePowerOutputEditFieldLabel.Text = 'Average Power Output';
            app.AveragePowerOutputEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kinetic_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end